classdef MIMO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NumarAnteneReceptieEditFieldLabel  matlab.ui.control.Label
        NumarAnteneReceptieEditField    matlab.ui.control.NumericEditField
        NumarUtilizatoriEditFieldLabel  matlab.ui.control.Label
        NumarUtilizatoriEditField       matlab.ui.control.NumericEditField
        TipuldeFadingEditFieldLabel     matlab.ui.control.Label
        TipuldeFadingEditField          matlab.ui.control.NumericEditField
        RulareButton                    matlab.ui.control.Button
        ProgrampentrusimulareSistemelorSISOMIMOMuMIMOLabel  matlab.ui.control.Label
        SISOCheckBox                    matlab.ui.control.CheckBox
        CheckBox2                       matlab.ui.control.CheckBox
        CheckBox3                       matlab.ui.control.CheckBox
    end

    methods (Access = private)

        % Value changed function: NumarAnteneReceptieEditField
        function NumarAnteneReceptieEditFieldValueChanged(app, event)
            global N
            N = app.NumarAnteneReceptieEditField.Value;
            
        end

        % Value changed function: NumarUtilizatoriEditField
        function NumarUtilizatoriEditFieldValueChanged(app, event)
            global M
            M = app.NumarUtilizatoriEditField.Value;
            
        end

        % Value changed function: TipuldeFadingEditField
        function TipuldeFadingEditFieldValueChanged(app, event)
            global f
            f = app.TipuldeFadingEditField.Value;
            
        end

        % Button pushed function: RulareButton
        function RulareButtonPushed(app, event)
            global N;
            global M;
            global f;
            [y] = MASSIVE_MIMO_f(1, N,1,M,f);
        end

        % Callback function
        function TipulSistemuluiButtonGroupSelectionChanged(app, event)

        end

        % Value changed function: SISOCheckBox
        function SISOCheckBoxValueChanged(app, event)
            global value_SISO;
            value_SISO = 0;
            value_SISO = app.SISOCheckBox.Value;
            
            switch value_SISO
                case 0 
                    app.NumarAnteneReceptieEditField.Enable = 'off';
                    app.NumarUtilizatoriEditField.Enable = 'off';
                case 1 
                    app.NumarAnteneReceptieEditField.Enable = 'on';
                    app.NumarUtilizatoriEditField.Enable = 'on';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NumarAnteneReceptieEditFieldLabel = uilabel(app.UIFigure);
            app.NumarAnteneReceptieEditFieldLabel.Text = 'Numar Antene Receptie';
            app.NumarAnteneReceptieEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumarAnteneReceptieEditField.ValueChangedFcn = createCallbackFcn(app, @NumarAnteneReceptieEditFieldValueChanged, true);
            app.NumarUtilizatoriEditFieldLabel = uilabel(app.UIFigure);
            app.NumarUtilizatoriEditFieldLabel.Text = 'Numar Utilizatori';
            app.NumarUtilizatoriEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumarUtilizatoriEditField.ValueChangedFcn = createCallbackFcn(app, @NumarUtilizatoriEditFieldValueChanged, true);
            app.TipuldeFadingEditFieldLabel = uilabel(app.UIFigure);
            app.TipuldeFadingEditFieldLabel.Text = 'Tipul de Fading';
            app.TipuldeFadingEditField = uieditfield(app.UIFigure, 'numeric');
            app.TipuldeFadingEditField.ValueChangedFcn = createCallbackFcn(app, @TipuldeFadingEditFieldValueChanged, true);
            app.RulareButton = uibutton(app.UIFigure, 'push');
            app.RulareButton.ButtonPushedFcn = createCallbackFcn(app, @RulareButtonPushed, true);
            app.RulareButton.Text = 'Rulare';
            app.ProgrampentrusimulareSistemelorSISOMIMOMuMIMOLabel = uilabel(app.UIFigure);
            app.ProgrampentrusimulareSistemelorSISOMIMOMuMIMOLabel.Text = 'Program pentru simulare Sistemelor SISO, MIMO, Mu-MIMO';
            app.SISOCheckBox = uicheckbox(app.UIFigure);
            app.SISOCheckBox.ValueChangedFcn = createCallbackFcn(app, @SISOCheckBoxValueChanged, true);
            app.SISOCheckBox.Text = 'SISO';
            app.CheckBox2 = uicheckbox(app.UIFigure);
            app.CheckBox2.Text = 'Check Box2';
            app.CheckBox3 = uicheckbox(app.UIFigure);
            app.CheckBox3.Text = 'Check Box3';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MIMO

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end