classdef Program_Simulari < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ProgrampentrusimulareSistemelorSISOMIMOMassiveMIMOLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        RulareButton                    matlab.ui.control.Button
        TipulSistemuluiButtonGroup      matlab.ui.container.ButtonGroup
        SISOButton                      matlab.ui.control.RadioButton
        MIMOButton                      matlab.ui.control.RadioButton
        MassiveMIMOButton               matlab.ui.control.RadioButton
        TipulModulatieiButtonGroup      matlab.ui.container.ButtonGroup
        BPSKButton                      matlab.ui.control.ToggleButton
        QPSKButton                      matlab.ui.control.ToggleButton
        QAMButton                       matlab.ui.control.ToggleButton
        QAMButton_2                     matlab.ui.control.ToggleButton
        Panel_2                         matlab.ui.container.Panel
        NumarAnteneReceptieEditFieldLabel  matlab.ui.control.Label
        NumarAnteneReceptieEditField    matlab.ui.control.NumericEditField
        NumarUtilizatoriEditFieldLabel  matlab.ui.control.Label
        NumarUtilizatoriEditField       matlab.ui.control.NumericEditField
        LimitaInferioaraRSZEditFieldLabel  matlab.ui.control.Label
        LimitaInferioaraRSZEditField    matlab.ui.control.NumericEditField
        LimitaSuperioaraRSZEditFieldLabel  matlab.ui.control.Label
        LimitaSuperioaraRSZEditField    matlab.ui.control.NumericEditField
        PasRSZEditFieldLabel            matlab.ui.control.Label
        PasRSZEditField                 matlab.ui.control.NumericEditField
        TipuldeFadingButtonGroup        matlab.ui.container.ButtonGroup
        RayleighButton                  matlab.ui.control.ToggleButton
        RiceButton                      matlab.ui.control.ToggleButton
        NakagamiButton                  matlab.ui.control.ToggleButton
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Lamp_6                          matlab.ui.control.Lamp
        Lamp2                           matlab.ui.control.Lamp
        Lamp2_2                         matlab.ui.control.Lamp
        Lamp2_3                         matlab.ui.control.Lamp
        Lamp2_4                         matlab.ui.control.Lamp
        Lamp2_5                         matlab.ui.control.Lamp
        Panel_3                         matlab.ui.container.Panel
        StartButton                     matlab.ui.control.StateButton
    end

    methods (Access = private)

        % Value changed function: NumarAnteneReceptieEditField
        function NumarAnteneReceptieEditFieldValueChanged(app, event)
            global N;
            global value_MuMIMO value_MIMO;
            N = app.NumarAnteneReceptieEditField.Value;
            if value_MuMIMO == 1
                app.NumarAnteneReceptieEditField.Limits = [9 100];
                if N > 8
                    app.Lamp2_2.Color = 'g';
                else
                    app.Lamp2_2.Color = 'r';
                end
            end
            
            if value_MIMO == 1
                app.NumarAnteneReceptieEditField.Limits = [1 8];
                if N <= 8
                    app.Lamp2_2.Color = 'g';
                else
                    app.Lamp2_2.Color = 'r';
                end
            end
        end

        % Value changed function: NumarUtilizatoriEditField
        function NumarUtilizatoriEditFieldValueChanged(app, event)
            global M;
            M = app.NumarUtilizatoriEditField.Value;
            app.Lamp2.Color = 'g';
            
        end

        % Callback function
        function TipuldeFadingEditFieldValueChanged(app, event)
          
        end

        % Button pushed function: RulareButton
        function RulareButtonPushed(app, event)
            global rsz tip_f tip_mod N M;
            global rsz_step rsz_max rsz_min;
            global value_SISO value_MIMO value_MuMIMO;
            global ber;
            
            rsz = rsz_min:rsz_step:rsz_max;
            
            if value_MIMO == 1
                ber = MIMO_f(N,M,tip_f,tip_mod,rsz);
                figure(1)
                semilogy(rsz,ber,'ro-','LineWidth',2);
                grid on
                xlabel('Eb/No [dB]');
                ylabel('Rata Erorii de Bit');
                
            end
            
            if value_MuMIMO == 1
                ber = MIMO_f(N,M,tip_f,tip_mod,rsz);
                figure(1)
                semilogy(rsz,ber,'ro-','LineWidth',2);
                grid on
                xlabel('Eb/No [dB]');
                ylabel('Rata Erorii de Bit');
                
            end
            
            if value_SISO == 1
                ber = SISO_f(tip_f,tip_mod,rsz);
                figure(1)
                semilogy(rsz,ber,'ro-','LineWidth',2);
                grid on
                xlabel('Eb/No [dB]');
                ylabel('Rata Erorii de Bit');
               
            end
        end

        % Callback function
        function TipulSistemuluiButtonGroupSelectionChanged(app, event)

        end

        % Callback function
        function SISOCheckBoxValueChanged(app, event)

        end

        % Selection changed function: TipulSistemuluiButtonGroup
        function TipulSistemuluiButtonGroupSelectionChanged2(app, event)
            selectedButton = app.TipulSistemuluiButtonGroup.SelectedObject;
            global value_SISO value_MIMO value_MuMIMO;
            value_SISO = app.SISOButton.Value;
            value_MIMO = app.MIMOButton.Value;
            value_MuMIMO = app.MassiveMIMOButton.Value;

            if app.SISOButton.Value == 1
                app.NumarAnteneReceptieEditField.Enable = 'off';
                app.NumarUtilizatoriEditField.Enable = 'off';
                app.Label_2.Visible = 'off';
                app.Label.Visible = 'off';
            end
            
            if app.MIMOButton.Value == 1
                app.NumarAnteneReceptieEditField.Enable = 'on';
                app.NumarUtilizatoriEditField.Enable = 'on';
                app.Label_2.Visible = 'on';
                app.Label.Visible = 'off';
            end
            
             if app.MassiveMIMOButton.Value == 1
                app.NumarAnteneReceptieEditField.Enable = 'on';
                app.NumarUtilizatoriEditField.Enable = 'on';
                app.Label.Visible = 'on';
                app.Label_2.Visible = 'off';
            end
        end

        % Value changed function: LimitaInferioaraRSZEditField
        function LimitaInferioaraRSZEditFieldValueChanged(app, event)
            global rsz_min;
            rsz_min = app.LimitaInferioaraRSZEditField.Value;   
            app.Lamp2_3.Color = 'g';
        end

        % Value changed function: LimitaSuperioaraRSZEditField
        function LimitaSuperioaraRSZEditFieldValueChanged(app, event)
            global rsz_max;
            global rsz_min;
            rsz_max = app.LimitaSuperioaraRSZEditField.Value; 
            if rsz_max <= rsz_min
                app.Lamp2_4.Color = 'r';
            else
                app.Lamp2_4.Color = 'g';
            end
        end

        % Value changed function: PasRSZEditField
        function PasRSZEditFieldValueChanged(app, event)
            global rsz_step;
            global rsz_max;
            rsz_step = app.PasRSZEditField.Value;
            if rsz_step>=rsz_max
                app.Lamp2_5.Color = 'r';
            else
                app.Lamp2_5.Color = 'g';
            end    
            
        end

        % Selection changed function: TipulModulatieiButtonGroup
        function TipulModulatieiButtonGroupSelectionChanged(app, event)
            selectedButton = app.TipulModulatieiButtonGroup.SelectedObject;
            global tip_mod;
            
            if app.BPSKButton.Value == 1
                tip_mod = 1;
            end
            
            if app.QPSKButton.Value == 1
                tip_mod = 2;
            end
            
            if app.QAMButton.Value == 1
                tip_mod = 3;
            end
            
            if app.QAMButton_2.Value == 1
                tip_mod = 4;
            end
            
           
        end

        % Selection changed function: TipuldeFadingButtonGroup
        function TipuldeFadingButtonGroupSelectionChanged(app, event)
            selectedButton = app.TipuldeFadingButtonGroup.SelectedObject;
            global tip_f;
            
            if app.RayleighButton.Value == 1
                tip_f = 1;
            end
            
            if app.RiceButton.Value == 1
                tip_f = 2;
            end
            
            if app.NakagamiButton.Value == 1
                tip_f = 3;
            end
           
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            if value == 1
                app.Lamp_6.Color = 'g';
                app.Panel_3.Visible = 'off';
                app.NumarAnteneReceptieEditField.Enable = 'off';
                app.NumarUtilizatoriEditField.Enable = 'off';
                app.Label.Visible = 'off';
                app.Label_2.Visible = 'off';
            end   
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ProgrampentrusimulareSistemelorSISOMIMOMassiveMIMOLabel = uilabel(app.UIFigure);
            app.ProgrampentrusimulareSistemelorSISOMIMOMassiveMIMOLabel.Text = 'Program pentru simulare Sistemelor SISO, MIMO, Massive MIMO';
            app.Panel = uipanel(app.UIFigure);
            app.RulareButton = uibutton(app.Panel, 'push');
            app.RulareButton.ButtonPushedFcn = createCallbackFcn(app, @RulareButtonPushed, true);
            app.RulareButton.Text = 'Rulare';
            app.TipulSistemuluiButtonGroup = uibuttongroup(app.UIFigure);
            app.TipulSistemuluiButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TipulSistemuluiButtonGroupSelectionChanged2, true);
            app.TipulSistemuluiButtonGroup.Title = 'Tipul Sistemului';
            app.SISOButton = uiradiobutton(app.TipulSistemuluiButtonGroup);
            app.SISOButton.Text = 'SISO';
            app.SISOButton.Value = true;
            app.MIMOButton = uiradiobutton(app.TipulSistemuluiButtonGroup);
            app.MIMOButton.Text = 'MIMO';
            app.MassiveMIMOButton = uiradiobutton(app.TipulSistemuluiButtonGroup);
            app.MassiveMIMOButton.Text = 'Massive MIMO';
            app.TipulModulatieiButtonGroup = uibuttongroup(app.UIFigure);
            app.TipulModulatieiButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TipulModulatieiButtonGroupSelectionChanged, true);
            app.TipulModulatieiButtonGroup.Title = 'Tipul Modulatiei';
            app.BPSKButton = uitogglebutton(app.TipulModulatieiButtonGroup);
            app.BPSKButton.Text = 'BPSK';
            app.BPSKButton.Value = true;
            app.QPSKButton = uitogglebutton(app.TipulModulatieiButtonGroup);
            app.QPSKButton.Text = 'QPSK';
            app.QAMButton = uitogglebutton(app.TipulModulatieiButtonGroup);
            app.QAMButton.Text = '8QAM';
            app.QAMButton_2 = uitogglebutton(app.TipulModulatieiButtonGroup);
            app.QAMButton_2.Text = '16QAM';
            app.Panel_2 = uipanel(app.UIFigure);
            app.NumarAnteneReceptieEditFieldLabel = uilabel(app.Panel_2);
            app.NumarAnteneReceptieEditFieldLabel.Text = 'Numar Antene Receptie';
            app.NumarAnteneReceptieEditField = uieditfield(app.Panel_2, 'numeric');
            app.NumarAnteneReceptieEditField.ValueChangedFcn = createCallbackFcn(app, @NumarAnteneReceptieEditFieldValueChanged, true);
            app.NumarUtilizatoriEditFieldLabel = uilabel(app.Panel_2);
            app.NumarUtilizatoriEditFieldLabel.Text = 'Numar Utilizatori';
            app.NumarUtilizatoriEditField = uieditfield(app.Panel_2, 'numeric');
            app.NumarUtilizatoriEditField.ValueChangedFcn = createCallbackFcn(app, @NumarUtilizatoriEditFieldValueChanged, true);
            app.LimitaInferioaraRSZEditFieldLabel = uilabel(app.Panel_2);
            app.LimitaInferioaraRSZEditFieldLabel.Text = 'Limita Inferioara RSZ';
            app.LimitaInferioaraRSZEditField = uieditfield(app.Panel_2, 'numeric');
            app.LimitaInferioaraRSZEditField.ValueChangedFcn = createCallbackFcn(app, @LimitaInferioaraRSZEditFieldValueChanged, true);
            app.LimitaSuperioaraRSZEditFieldLabel = uilabel(app.Panel_2);
            app.LimitaSuperioaraRSZEditFieldLabel.Text = 'Limita Superioara RSZ';
            app.LimitaSuperioaraRSZEditField = uieditfield(app.Panel_2, 'numeric');
            app.LimitaSuperioaraRSZEditField.ValueChangedFcn = createCallbackFcn(app, @LimitaSuperioaraRSZEditFieldValueChanged, true);
            app.PasRSZEditFieldLabel = uilabel(app.Panel_2);
            app.PasRSZEditFieldLabel.Text = 'Pas RSZ';
            app.PasRSZEditField = uieditfield(app.Panel_2, 'numeric');
            app.PasRSZEditField.ValueChangedFcn = createCallbackFcn(app, @PasRSZEditFieldValueChanged, true);
            app.TipuldeFadingButtonGroup = uibuttongroup(app.UIFigure);
            app.TipuldeFadingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TipuldeFadingButtonGroupSelectionChanged, true);
            app.TipuldeFadingButtonGroup.Title = 'Tipul de Fading';
            app.RayleighButton = uitogglebutton(app.TipuldeFadingButtonGroup);
            app.RayleighButton.Text = 'Rayleigh';
            app.RayleighButton.Value = true;
            app.RiceButton = uitogglebutton(app.TipuldeFadingButtonGroup);
            app.RiceButton.Text = 'Rice';
            app.NakagamiButton = uitogglebutton(app.TipuldeFadingButtonGroup);
            app.NakagamiButton.Text = 'Nakagami';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '>8';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '≤8';
            app.Lamp_6 = uilamp(app.UIFigure);
            app.Lamp2 = uilamp(app.UIFigure);
            app.Lamp2_2 = uilamp(app.UIFigure);
            app.Lamp2_3 = uilamp(app.UIFigure);
            app.Lamp2_4 = uilamp(app.UIFigure);
            app.Lamp2_5 = uilamp(app.UIFigure);
            app.Panel_3 = uipanel(app.UIFigure);
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Program_Simulari

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end